classdef kadai1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Label_2        matlab.ui.control.Label
        Label          matlab.ui.control.Label
        computeButton  matlab.ui.control.Button
        loadButton     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
        % read image
        im1=imread("logo_csse.png");
        % % plot image
        figure= app.UIFigure;
        ax1 = uiaxes(figure,'Position',[82 267 321 116]);
        imshow(im1,'Parent',ax1)
        end

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)
        
        % read image
        im2=imread("logo_csse.png");
        % compute image
        imgray = rgb2gray(im2);
        BW = imbinarize(imgray);
        % plot images
        figure= app.UIFigure;
        ax2 = uiaxes(figure,'Position',[95 97 321 116]);

        imshow(BW*255,'Parent',ax2);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.computeButton = uibutton(app.UIFigure, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'compute';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '画像表示';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '二値化';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kadai1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end